classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        UploadMainImageTab        matlab.ui.container.Tab
        UploadButton              matlab.ui.control.Button
        mImgUIAxes                matlab.ui.control.UIAxes
        RecoverButton             matlab.ui.control.Button
        InfoMainLabel             matlab.ui.control.Label
        NextButton                matlab.ui.control.Button
        MainLabel                 matlab.ui.control.Label
        ClickLabel                matlab.ui.control.Label
        RecoverInfoLabel          matlab.ui.control.Label
        EmbedAppLabel             matlab.ui.control.Label
        CRMatlabManiacs2019Label  matlab.ui.control.Label
        EmbeddingTypeTab          matlab.ui.container.Tab
        ThreeButton               matlab.ui.control.Button
        TwoButton                 matlab.ui.control.Button
        SingleButton              matlab.ui.control.Button
        MessageButton             matlab.ui.control.Button
        EmbedLabel                matlab.ui.control.Label
        demoOddUIAxes             matlab.ui.control.UIAxes
        demoColorUIAxes           matlab.ui.control.UIAxes
        demoMessageUIAxes         matlab.ui.control.UIAxes
        OEembedLabel              matlab.ui.control.Label
        suggestLabel              matlab.ui.control.Label
        BinaryLabel               matlab.ui.control.Label
        BinaryLabel2              matlab.ui.control.Label
        suggestLabel2             matlab.ui.control.Label
        ColorButton               matlab.ui.control.Button
        suggestLabel3             matlab.ui.control.Label
        tenButton                 matlab.ui.control.Button
        suggestLabel_2            matlab.ui.control.Label
        SingleTab                 matlab.ui.container.Tab
        singleUIAxes              matlab.ui.control.UIAxes
        ColorImageLabel_2         matlab.ui.control.Label
        revealSingleButton        matlab.ui.control.Button
        singleButton              matlab.ui.control.Button
        infoColorLabel_2          matlab.ui.control.Label
        EmbeddedInfoLabel_3       matlab.ui.control.Label
        TwoTab                    matlab.ui.container.Tab
        twoUIAxes1                matlab.ui.control.UIAxes
        ColorImageLabel_3         matlab.ui.control.Label
        revealDoubleButton        matlab.ui.control.Button
        doubleButton1             matlab.ui.control.Button
        infoColorLabel_3          matlab.ui.control.Label
        EmbeddedInfoLabel_4       matlab.ui.control.Label
        twoUIAxes2                matlab.ui.control.UIAxes
        doubleButton2             matlab.ui.control.Button
        ThreeTab                  matlab.ui.container.Tab
        ColorImageLabel_4         matlab.ui.control.Label
        infoColorLabel_4          matlab.ui.control.Label
        threeUIAxes1              matlab.ui.control.UIAxes
        revealThreeButton         matlab.ui.control.Button
        threeButton1              matlab.ui.control.Button
        EmbeddedInfoLabel_5       matlab.ui.control.Label
        threeUIAxes2              matlab.ui.control.UIAxes
        threeButton2              matlab.ui.control.Button
        threeUIAxes3              matlab.ui.control.UIAxes
        threeButton3              matlab.ui.control.Button
        tenTab                    matlab.ui.container.Tab
        ColorImageLabel_5         matlab.ui.control.Label
        infoColorLabel_5          matlab.ui.control.Label
        infoColorLabel2_5         matlab.ui.control.Label
        tenUIAxes                 matlab.ui.control.UIAxes
        revealTen                 matlab.ui.control.Button
        EmbeddedInfoLabel_6       matlab.ui.control.Label
        ten2Button                matlab.ui.control.Button
        ten1Button                matlab.ui.control.Button
        ten3Button                matlab.ui.control.Button
        ten4Button                matlab.ui.control.Button
        ten5Button                matlab.ui.control.Button
        ten8Button                matlab.ui.control.Button
        ten7Button                matlab.ui.control.Button
        ten9Button                matlab.ui.control.Button
        ten10Button               matlab.ui.control.Button
        ten6Button                matlab.ui.control.Button
        imagesmustbesamesizeasmainLabel  matlab.ui.control.Label
        LoadSecretColorImageTab   matlab.ui.container.Tab
        hImgUIAxes                matlab.ui.control.UIAxes
        ColorImageLabel           matlab.ui.control.Label
        revealColor               matlab.ui.control.Button
        SecretButton              matlab.ui.control.Button
        infoColorLabel            matlab.ui.control.Label
        infoColorLabel2           matlab.ui.control.Label
        EmbeddedInfoLabel         matlab.ui.control.Label
        EnterSecretMessageTab     matlab.ui.container.Tab
        AddasecretmessageLabel    matlab.ui.control.Label
        secretMessageEditField    matlab.ui.control.EditField
        embedMessageButton        matlab.ui.control.Button
        SecretLabel               matlab.ui.control.Label
        InfoSecretLabel           matlab.ui.control.Label
        EmbeddedInfoLabel_2       matlab.ui.control.Label
        EmbeddedImageTab          matlab.ui.container.Tab
        EmbeddedLabel             matlab.ui.control.Label
        embeddedUIAxes            matlab.ui.control.UIAxes
        RecoverButton2            matlab.ui.control.Button
        RecoverInfoEmbedLabel     matlab.ui.control.Label
        saveImageButton           matlab.ui.control.Button
        SaveyourimageinpngforbestresultsLabel  matlab.ui.control.Label
        RecoverButton2_2          matlab.ui.control.Button
    end


    properties (Access = private)
        % if i were to give out this app how do we load and store the image
        tempMainImg = imread('MainTemp.png');
        tempBW = imread('blackAndwhite.png');
        tempColor = imread('colorTemp.png');
        tempMessage = imread('messageTemp.png');
        tempEmbedded = imread('EmbeddedTemp.png');
        
        mainImg;% front image
        hiddenColorImg; %hidden color image
        hiddenMessageImg; %embedded message in main img
        secretMessage;
        
        singleImg;
        doubleImg1;
        doubleImg2;
        tripleImg1;
        tripleImg2;
        tripleImg3;
        
        ten1Img;
        ten2Img;
        ten3Img;
        ten4Img;
        ten5Img;
        ten6Img;
        ten7Img;
        ten8Img;
        ten9Img;
        ten10Img;
        
    end

    methods (Access = private)
         
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %temp image main axes
            title(app.mImgUIAxes, []);
            xlabel(app.mImgUIAxes, []);
            ylabel(app.mImgUIAxes, []);
            app.mImgUIAxes.XAxis.TickLabels = {};
            app.mImgUIAxes.YAxis.TickLabels = {};
            imshow(app.tempMainImg,'Parent', app.mImgUIAxes);
            
            %temp image BW axes
            title(app.demoOddUIAxes, []);
            xlabel(app.demoOddUIAxes, []);
            ylabel(app.demoOddUIAxes, []);
            app.demoOddUIAxes.XAxis.TickLabels = {};
            app.demoOddUIAxes.YAxis.TickLabels = {};
            imshow(app.tempBW,'Parent', app.demoOddUIAxes);
            
            %temp image C axes
            title(app.demoColorUIAxes, []);
            xlabel(app.demoColorUIAxes, []);
            ylabel(app.demoColorUIAxes, []);
            app.demoColorUIAxes.XAxis.TickLabels = {};
            app.demoColorUIAxes.YAxis.TickLabels = {};
            imshow(app.tempColor,'Parent', app.demoColorUIAxes);
            
            %temp image message axes
            title(app.demoMessageUIAxes, []);
            xlabel(app.demoMessageUIAxes, []);
            ylabel(app.demoMessageUIAxes, []);
            app.demoMessageUIAxes.XAxis.TickLabels = {};
            app.demoMessageUIAxes.YAxis.TickLabels = {};
            imshow(app.tempMessage,'Parent', app.demoMessageUIAxes);
            
            %temp image sec color axes
            title(app.hImgUIAxes, []);
            xlabel(app.hImgUIAxes, []);
            ylabel(app.hImgUIAxes, []);
            app.hImgUIAxes.XAxis.TickLabels = {};
            app.hImgUIAxes.YAxis.TickLabels = {};
            imshow(app.tempColor,'Parent', app.hImgUIAxes);
            
            %temp image Embedded axes
            title(app.embeddedUIAxes, []);
            xlabel(app.embeddedUIAxes, []);
            ylabel(app.embeddedUIAxes, []);
            app.embeddedUIAxes.XAxis.TickLabels = {};
            app.embeddedUIAxes.YAxis.TickLabels = {};
            imshow(app.tempEmbedded,'Parent', app.embeddedUIAxes);
            
            
            %-------------------
            
            %temp image single axes
            title(app.singleUIAxes, []);
            xlabel(app.singleUIAxes, []);
            ylabel(app.singleUIAxes, []);
            app.singleUIAxes.XAxis.TickLabels = {};
            app.singleUIAxes.YAxis.TickLabels = {};
            imshow(app.tempBW,'Parent', app.singleUIAxes);
            
            %temp image double 1 axes
            title(app.twoUIAxes1, []);
            xlabel(app.twoUIAxes1, []);
            ylabel(app.twoUIAxes1, []);
            app.twoUIAxes1.XAxis.TickLabels = {};
            app.twoUIAxes1.YAxis.TickLabels = {};
            imshow(app.tempBW,'Parent', app.twoUIAxes1);
            
            %temp image double 2 axes
            title(app.twoUIAxes2, []);
            xlabel(app.twoUIAxes2, []);
            ylabel(app.twoUIAxes2, []);
            app.twoUIAxes2.XAxis.TickLabels = {};
            app.twoUIAxes2.YAxis.TickLabels = {};
            imshow(app.tempBW,'Parent', app.twoUIAxes2);
            
            %temp image three 1 axes
            title(app.threeUIAxes1, []);
            xlabel(app.threeUIAxes1, []);
            ylabel(app.threeUIAxes1, []);
            app.threeUIAxes1.XAxis.TickLabels = {};
            app.threeUIAxes1.YAxis.TickLabels = {};
            imshow(app.tempBW,'Parent', app.threeUIAxes1);
            
            %temp image three 2 axes
            title(app.threeUIAxes2, []);
            xlabel(app.threeUIAxes2, []);
            ylabel(app.threeUIAxes2, []);
            app.threeUIAxes2.XAxis.TickLabels = {};
            app.threeUIAxes2.YAxis.TickLabels = {};
            imshow(app.tempBW,'Parent', app.threeUIAxes2);
            
             %temp image three 3 axes
            title(app.threeUIAxes3, []);
            xlabel(app.threeUIAxes3, []);
            ylabel(app.threeUIAxes3, []);
            app.threeUIAxes3.XAxis.TickLabels = {};
            app.threeUIAxes3.YAxis.TickLabels = {};
            imshow(app.tempBW,'Parent', app.threeUIAxes3);
            
            %temp image 10 axes
            title(app.tenUIAxes, []);
            xlabel(app.tenUIAxes, []);
            ylabel(app.tenUIAxes, []);
            app.tenUIAxes.XAxis.TickLabels = {};
            app.tenUIAxes.YAxis.TickLabels = {};
            imshow(app.tempBW,'Parent', app.tenUIAxes);
            
            
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            
            app.mainImg=imread(uigetfile({'*.png';'*.jpg'}, 'Select file'));
            imshow(app.mainImg,'Parent', app.mImgUIAxes);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';

            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.EmbeddingTypeTab;

        end

        % Button pushed function: ColorButton
        function ColorButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.LoadSecretColorImageTab;

        end

        % Button pushed function: MessageButton
        function MessageButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.EnterSecretMessageTab;

        end

        % Button pushed function: SecretButton
        function SecretButtonPushed(app, event)
            app.hiddenColorImg=imread(uigetfile({'*.png';'*.jpg'}, 'Select file'));
            imshow(app.hiddenColorImg,'Parent', app.hImgUIAxes);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: revealColor
        function revealColorPushed(app, event)
            embeddedImg = embedColorImage(app.mainImg,app.hiddenColorImg);%calles the .m file in matlab
            imshow(embeddedImg,'Parent', app.embeddedUIAxes);
            app.TabGroup.SelectedTab = app.EmbeddedImageTab;
        end

        % Button pushed function: saveImageButton
        function saveImageButtonPushed(app, event)
            imsave(app.embeddedUIAxes);  
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: secretMessageEditField
        function secretMessageEditFieldValueChanged(app, event)
            app.secretMessage = app.secretMessageEditField.Value;
            
        end

        % Button pushed function: embedMessageButton
        function embedMessageButtonPushed(app, event)
            app.hiddenMessageImg = embedMessage(app.secretMessage, app.mainImg); %calles the .m file in matlab
            imshow(app.hiddenMessageImg,'Parent', app.embeddedUIAxes);
            app.TabGroup.SelectedTab = app.EmbeddedImageTab;
        end

        % Button pushed function: RecoverButton
        function RecoverButtonPushed(app, event)
            delete(app);                 
            app2;
        end

        % Button pushed function: RecoverButton2_2
        function RecoverButton2_2Pushed(app, event)
            delete(app);                 
            app2;
        end

        % Button pushed function: SingleButton
        function SingleButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.SingleTab;
        end

        % Button pushed function: TwoButton
        function TwoButtonPushed(app, event)
             app.TabGroup.SelectedTab = app.TwoTab;
        end

        % Button pushed function: ThreeButton
        function ThreeButtonPushed(app, event)
             app.TabGroup.SelectedTab = app.ThreeTab;
        end

        % Button pushed function: singleButton
        function singleButtonPushed(app, event)
           app.singleImg = imread(uigetfile({'*.png';'*.jpg'}, 'Select file'));
           imshow(app.singleImg,'Parent', app.singleUIAxes);
           app.UIFigure.Visible = 'off';
           app.UIFigure.Visible = 'on';
        end

        % Button pushed function: revealSingleButton
        function revealSingleButtonPushed(app, event)
            embedSingleImg = singleEmb(app.mainImg,app.singleImg);           
            imshow(embedSingleImg,'Parent', app.embeddedUIAxes);
            app.TabGroup.SelectedTab = app.EmbeddedImageTab;

        end

        % Button pushed function: doubleButton1
        function doubleButton1Pushed(app, event)
            app.doubleImg1=imread(uigetfile({'*.png';'*.jpg'}, 'Select file'));
            imshow(app.doubleImg1,'Parent', app.twoUIAxes1);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';

        end

        % Button pushed function: doubleButton2
        function doubleButton2Pushed(app, event)
            app.doubleImg2=imread(uigetfile({'*.png';'*.jpg'}, 'Select file'));
            imshow(app.doubleImg2,'Parent', app.twoUIAxes2);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';

        end

        % Button pushed function: revealDoubleButton
        function revealDoubleButtonPushed(app, event)
            embedDoubleImg = doubleEmb(app.mainImg,app.doubleImg1, app.doubleImg2);%calles the .m file in matlab
            imshow(embedDoubleImg,'Parent', app.embeddedUIAxes);
            app.TabGroup.SelectedTab = app.EmbeddedImageTab;
        end

        % Button pushed function: threeButton1
        function threeButton1Pushed(app, event)
            app.tripleImg1=imread(uigetfile({'*.png';'*.jpg'}, 'Select file'));
            imshow(app.tripleImg1,'Parent', app.threeUIAxes1);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';

        end

        % Button pushed function: threeButton2
        function threeButton2Pushed(app, event)
            app.tripleImg2=imread(uigetfile({'*.png';'*.jpg'}, 'Select file'));
            imshow(app.tripleImg2,'Parent', app.threeUIAxes2);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: threeButton3
        function threeButton3Pushed(app, event)
            app.tripleImg3=imread(uigetfile({'*.png';'*.jpg'}, 'Select file'));
            imshow(app.tripleImg3,'Parent', app.threeUIAxes3);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: revealThreeButton
        function revealThreeButtonPushed(app, event)
            embedThreeImg = tripleEmb(app.mainImg,app.tripleImg1, app.tripleImg2, app.tripleImg3); %calles the .m file in matlab
            imshow(embedThreeImg,'Parent', app.embeddedUIAxes);
            app.TabGroup.SelectedTab = app.EmbeddedImageTab;
        end

        % Button pushed function: tenButton
        function tenButtonPushed(app, event)
             app.TabGroup.SelectedTab = app.tenTab;
        end

        % Button pushed function: ten1Button
        function ten1ButtonPushed(app, event)
            app.ten1Img=imread(uigetfile({'*.png';'*.jpg'}, 'Select file'));
            imshow(app.ten1Img,'Parent', app.tenUIAxes);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: ten2Button
        function ten2ButtonPushed(app, event)
            app.ten2Img=imread(uigetfile({'*.png';'*.jpg'}, 'Select file'));
            imshow(app.ten2Img,'Parent', app.tenUIAxes);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: ten3Button
        function ten3ButtonPushed(app, event)
            app.ten3Img=imread(uigetfile({'*.png';'*.jpg'}, 'Select file'));
            imshow(app.ten3Img,'Parent', app.tenUIAxes);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: ten4Button
        function ten4ButtonPushed(app, event)
            app.ten4Img=imread(uigetfile({'*.png';'*.jpg'}, 'Select file'));
            imshow(app.ten4Img,'Parent', app.tenUIAxes);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: ten5Button
        function ten5ButtonPushed(app, event)
            app.ten5Img=imread(uigetfile({'*.png';'*.jpg'}, 'Select file'));
            imshow(app.ten5Img,'Parent', app.tenUIAxes);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: ten8Button
        function ten8ButtonPushed(app, event)
            app.ten8Img=imread(uigetfile({'*.png';'*.jpg'}, 'Select file'));
            imshow(app.ten8Img,'Parent', app.tenUIAxes);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: ten7Button
        function ten7ButtonPushed(app, event)
            app.ten7Img=imread(uigetfile({'*.png';'*.jpg'}, 'Select file'));
            imshow(app.ten7Img,'Parent', app.tenUIAxes);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: ten9Button
        function ten9ButtonPushed(app, event)
            app.ten9Img=imread(uigetfile({'*.png';'*.jpg'}, 'Select file'));
            imshow(app.ten9Img,'Parent', app.tenUIAxes);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: ten10Button
        function ten10ButtonPushed(app, event)
            app.ten10Img=imread(uigetfile({'*.png';'*.jpg'}, 'Select file'));
            imshow(app.ten10Img,'Parent', app.tenUIAxes);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: ten6Button
        function ten6ButtonPushed(app, event)
            app.ten6Img=imread(uigetfile({'*.png';'*.jpg'}, 'Select file'));
            imshow(app.ten6Img,'Parent', app.tenUIAxes);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: revealTen
        function revealTenPushed(app, event)
            embedTenImg = tenEmb(app.mainImg,app.ten1Img, app.ten2Img,app.ten3Img,...
                app.ten4Img,app.ten5Img,app.ten6Img,...
                app.ten7Img,app.ten8Img,app.ten9Img,app.ten10Img);%calles the .m file in matlab
            imshow(embedTenImg,'Parent', app.embeddedUIAxes);
            app.TabGroup.SelectedTab = app.EmbeddedImageTab;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UploadMainImageTab = uitab(app.TabGroup);
            app.UploadMainImageTab.Title = 'Upload Main Image';
            app.UploadButton = uibutton(app.UploadMainImageTab, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload - Main Image';
            app.mImgUIAxes = uiaxes(app.UploadMainImageTab);
            title(app.mImgUIAxes, 'Title')
            xlabel(app.mImgUIAxes, 'X')
            ylabel(app.mImgUIAxes, 'Y')
            app.RecoverButton = uibutton(app.UploadMainImageTab, 'push');
            app.RecoverButton.ButtonPushedFcn = createCallbackFcn(app, @RecoverButtonPushed, true);
            app.RecoverButton.Text = 'Recover an Image';
            app.InfoMainLabel = uilabel(app.UploadMainImageTab);
            app.InfoMainLabel.Text = '*Your main Image will cover your secret information.';
            app.NextButton = uibutton(app.UploadMainImageTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.MainLabel = uilabel(app.UploadMainImageTab);
            app.MainLabel.Text = 'Upload Your Main Image:';
            app.ClickLabel = uilabel(app.UploadMainImageTab);
            app.ClickLabel.Text = '*Click the button to load your Main image';
            app.RecoverInfoLabel = uilabel(app.UploadMainImageTab);
            app.RecoverInfoLabel.Text = '*Already have an embedded image click here';
            app.EmbedAppLabel = uilabel(app.UploadMainImageTab);
            app.EmbedAppLabel.Text = 'Embed App';
            app.CRMatlabManiacs2019Label = uilabel(app.UploadMainImageTab);
            app.CRMatlabManiacs2019Label.Text = 'C.R Matlab Maniacs - 2019';
            app.EmbeddingTypeTab = uitab(app.TabGroup);
            app.EmbeddingTypeTab.Title = 'Embedding Type';
            app.ThreeButton = uibutton(app.EmbeddingTypeTab, 'push');
            app.ThreeButton.ButtonPushedFcn = createCallbackFcn(app, @ThreeButtonPushed, true);
            app.ThreeButton.Text = '3 Images (B/W)';
            app.TwoButton = uibutton(app.EmbeddingTypeTab, 'push');
            app.TwoButton.ButtonPushedFcn = createCallbackFcn(app, @TwoButtonPushed, true);
            app.TwoButton.Text = '2 Images (B/W)';
            app.SingleButton = uibutton(app.EmbeddingTypeTab, 'push');
            app.SingleButton.ButtonPushedFcn = createCallbackFcn(app, @SingleButtonPushed, true);
            app.SingleButton.Text = '1 Image (B/W)';
            app.MessageButton = uibutton(app.EmbeddingTypeTab, 'push');
            app.MessageButton.ButtonPushedFcn = createCallbackFcn(app, @MessageButtonPushed, true);
            app.MessageButton.Text = 'Message';
            app.EmbedLabel = uilabel(app.EmbeddingTypeTab);
            app.EmbedLabel.Text = 'Select the Information you would like to Embed:';
            app.demoOddUIAxes = uiaxes(app.EmbeddingTypeTab);
            title(app.demoOddUIAxes, 'Title')
            xlabel(app.demoOddUIAxes, 'X')
            ylabel(app.demoOddUIAxes, 'Y')
            app.demoColorUIAxes = uiaxes(app.EmbeddingTypeTab);
            title(app.demoColorUIAxes, 'Title')
            xlabel(app.demoColorUIAxes, 'X')
            ylabel(app.demoColorUIAxes, 'Y')
            app.demoMessageUIAxes = uiaxes(app.EmbeddingTypeTab);
            title(app.demoMessageUIAxes, 'Title')
            xlabel(app.demoMessageUIAxes, 'X')
            ylabel(app.demoMessageUIAxes, 'Y')
            app.OEembedLabel = uilabel(app.EmbeddingTypeTab);
            app.OEembedLabel.Text = '*Odd/Even Embedding Type';
            app.suggestLabel = uilabel(app.EmbeddingTypeTab);
            app.suggestLabel.Text = ' works best for black / white images';
            app.BinaryLabel = uilabel(app.EmbeddingTypeTab);
            app.BinaryLabel.Text = '*Binary Embedding Type';
            app.BinaryLabel2 = uilabel(app.EmbeddingTypeTab);
            app.BinaryLabel2.Text = '*Binary Embedding Type';
            app.suggestLabel2 = uilabel(app.EmbeddingTypeTab);
            app.suggestLabel2.Text = 'works for color (C) & (B/W) images';
            app.ColorButton = uibutton(app.EmbeddingTypeTab, 'push');
            app.ColorButton.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPushed, true);
            app.ColorButton.Text = 'Single Image (C)';
            app.suggestLabel3 = uilabel(app.EmbeddingTypeTab);
            app.suggestLabel3.Text = 'works best for small messages';
            app.tenButton = uibutton(app.EmbeddingTypeTab, 'push');
            app.tenButton.ButtonPushedFcn = createCallbackFcn(app, @tenButtonPushed, true);
            app.tenButton.Text = '10 Images (B/W)';
            app.suggestLabel_2 = uilabel(app.EmbeddingTypeTab);
            app.suggestLabel_2.Text = '*10 images option uses binary embed';
            app.SingleTab = uitab(app.TabGroup);
            app.SingleTab.Title = 'Load Single Image (B/W)';
            app.singleUIAxes = uiaxes(app.SingleTab);
            title(app.singleUIAxes, 'Title')
            xlabel(app.singleUIAxes, 'X')
            ylabel(app.singleUIAxes, 'Y')
            app.ColorImageLabel_2 = uilabel(app.SingleTab);
            app.ColorImageLabel_2.Text = 'Upload 1 (B/W) Image:';
            app.revealSingleButton = uibutton(app.SingleTab, 'push');
            app.revealSingleButton.ButtonPushedFcn = createCallbackFcn(app, @revealSingleButtonPushed, true);
            app.revealSingleButton.Text = 'Reveal - Your Embedded Image';
            app.singleButton = uibutton(app.SingleTab, 'push');
            app.singleButton.ButtonPushedFcn = createCallbackFcn(app, @singleButtonPushed, true);
            app.singleButton.Text = 'Upload - A Secret Image';
            app.infoColorLabel_2 = uilabel(app.SingleTab);
            app.infoColorLabel_2.Text = '*This encoding type can handle (B/W) images, image must be same size as main';
            app.EmbeddedInfoLabel_3 = uilabel(app.SingleTab);
            app.EmbeddedInfoLabel_3.Text = '*This may take some time to load ';
            app.TwoTab = uitab(app.TabGroup);
            app.TwoTab.Title = 'Load Two Images (B/W)';
            app.twoUIAxes1 = uiaxes(app.TwoTab);
            title(app.twoUIAxes1, 'Title')
            xlabel(app.twoUIAxes1, 'X')
            ylabel(app.twoUIAxes1, 'Y')
            app.ColorImageLabel_3 = uilabel(app.TwoTab);
            app.ColorImageLabel_3.Text = 'Upload 2 (B/W) Images:';
            app.revealDoubleButton = uibutton(app.TwoTab, 'push');
            app.revealDoubleButton.ButtonPushedFcn = createCallbackFcn(app, @revealDoubleButtonPushed, true);
            app.revealDoubleButton.Text = 'Reveal - Your Embedded Image';
            app.doubleButton1 = uibutton(app.TwoTab, 'push');
            app.doubleButton1.ButtonPushedFcn = createCallbackFcn(app, @doubleButton1Pushed, true);
            app.doubleButton1.Text = 'Upload - A Secret Image';
            app.infoColorLabel_3 = uilabel(app.TwoTab);
            app.infoColorLabel_3.Text = '*This encoding type can handle (B/W) images,  image must be same size as main';
            app.EmbeddedInfoLabel_4 = uilabel(app.TwoTab);
            app.EmbeddedInfoLabel_4.Text = '*This may take some time to load ';
            app.twoUIAxes2 = uiaxes(app.TwoTab);
            title(app.twoUIAxes2, 'Title')
            xlabel(app.twoUIAxes2, 'X')
            ylabel(app.twoUIAxes2, 'Y')
            app.doubleButton2 = uibutton(app.TwoTab, 'push');
            app.doubleButton2.ButtonPushedFcn = createCallbackFcn(app, @doubleButton2Pushed, true);
            app.doubleButton2.Text = 'Upload - A Secret Image';
            app.ThreeTab = uitab(app.TabGroup);
            app.ThreeTab.Title = 'Load Three Images (B/W)';
            app.ColorImageLabel_4 = uilabel(app.ThreeTab);
            app.ColorImageLabel_4.Text = 'Upload 3 (B/W) Images:';
            app.infoColorLabel_4 = uilabel(app.ThreeTab);
            app.infoColorLabel_4.Text = '*This encoding type can handle (B/W) images,  image must be same size as main';
            app.threeUIAxes1 = uiaxes(app.ThreeTab);
            title(app.threeUIAxes1, 'Title')
            xlabel(app.threeUIAxes1, 'X')
            ylabel(app.threeUIAxes1, 'Y')
            app.revealThreeButton = uibutton(app.ThreeTab, 'push');
            app.revealThreeButton.ButtonPushedFcn = createCallbackFcn(app, @revealThreeButtonPushed, true);
            app.revealThreeButton.Text = 'Reveal - Your Embedded Image';
            app.threeButton1 = uibutton(app.ThreeTab, 'push');
            app.threeButton1.ButtonPushedFcn = createCallbackFcn(app, @threeButton1Pushed, true);
            app.threeButton1.Text = 'Upload - A Secret Image';
            app.EmbeddedInfoLabel_5 = uilabel(app.ThreeTab);
            app.EmbeddedInfoLabel_5.Text = '*This may take some time to load ';
            app.threeUIAxes2 = uiaxes(app.ThreeTab);
            title(app.threeUIAxes2, 'Title')
            xlabel(app.threeUIAxes2, 'X')
            ylabel(app.threeUIAxes2, 'Y')
            app.threeButton2 = uibutton(app.ThreeTab, 'push');
            app.threeButton2.ButtonPushedFcn = createCallbackFcn(app, @threeButton2Pushed, true);
            app.threeButton2.Text = 'Upload - A Secret Image';
            app.threeUIAxes3 = uiaxes(app.ThreeTab);
            title(app.threeUIAxes3, 'Title')
            xlabel(app.threeUIAxes3, 'X')
            ylabel(app.threeUIAxes3, 'Y')
            app.threeButton3 = uibutton(app.ThreeTab, 'push');
            app.threeButton3.ButtonPushedFcn = createCallbackFcn(app, @threeButton3Pushed, true);
            app.threeButton3.Text = 'Upload - A Secret Image';
            app.tenTab = uitab(app.TabGroup);
            app.tenTab.Title = 'Load Ten Images (B/W)';
            app.ColorImageLabel_5 = uilabel(app.tenTab);
            app.ColorImageLabel_5.Text = 'Upload 10 (B/W) Images:';
            app.infoColorLabel_5 = uilabel(app.tenTab);
            app.infoColorLabel_5.Text = '*This encoding type can handle (B/W) images';
            app.infoColorLabel2_5 = uilabel(app.tenTab);
            app.infoColorLabel2_5.Text = 'Each button will load a separate image.';
            app.tenUIAxes = uiaxes(app.tenTab);
            title(app.tenUIAxes, 'Title')
            xlabel(app.tenUIAxes, 'X')
            ylabel(app.tenUIAxes, 'Y')
            app.revealTen = uibutton(app.tenTab, 'push');
            app.revealTen.ButtonPushedFcn = createCallbackFcn(app, @revealTenPushed, true);
            app.revealTen.Text = 'Reveal - Your Embedded Image';
            app.EmbeddedInfoLabel_6 = uilabel(app.tenTab);
            app.EmbeddedInfoLabel_6.Text = '*This may take some time to load ';
            app.ten2Button = uibutton(app.tenTab, 'push');
            app.ten2Button.ButtonPushedFcn = createCallbackFcn(app, @ten2ButtonPushed, true);
            app.ten2Button.Text = 'Upload (2)';
            app.ten1Button = uibutton(app.tenTab, 'push');
            app.ten1Button.ButtonPushedFcn = createCallbackFcn(app, @ten1ButtonPushed, true);
            app.ten1Button.Text = 'Upload (1)';
            app.ten3Button = uibutton(app.tenTab, 'push');
            app.ten3Button.ButtonPushedFcn = createCallbackFcn(app, @ten3ButtonPushed, true);
            app.ten3Button.Text = 'Upload (3)';
            app.ten4Button = uibutton(app.tenTab, 'push');
            app.ten4Button.ButtonPushedFcn = createCallbackFcn(app, @ten4ButtonPushed, true);
            app.ten4Button.Text = 'Upload (4)';
            app.ten5Button = uibutton(app.tenTab, 'push');
            app.ten5Button.ButtonPushedFcn = createCallbackFcn(app, @ten5ButtonPushed, true);
            app.ten5Button.Text = 'Upload (5)';
            app.ten8Button = uibutton(app.tenTab, 'push');
            app.ten8Button.ButtonPushedFcn = createCallbackFcn(app, @ten8ButtonPushed, true);
            app.ten8Button.Text = 'Upload (8)';
            app.ten7Button = uibutton(app.tenTab, 'push');
            app.ten7Button.ButtonPushedFcn = createCallbackFcn(app, @ten7ButtonPushed, true);
            app.ten7Button.Text = 'Upload (7)';
            app.ten9Button = uibutton(app.tenTab, 'push');
            app.ten9Button.ButtonPushedFcn = createCallbackFcn(app, @ten9ButtonPushed, true);
            app.ten9Button.Text = 'Upload (9)';
            app.ten10Button = uibutton(app.tenTab, 'push');
            app.ten10Button.ButtonPushedFcn = createCallbackFcn(app, @ten10ButtonPushed, true);
            app.ten10Button.Text = 'Upload (10)';
            app.ten6Button = uibutton(app.tenTab, 'push');
            app.ten6Button.ButtonPushedFcn = createCallbackFcn(app, @ten6ButtonPushed, true);
            app.ten6Button.Text = 'Upload (6)';
            app.imagesmustbesamesizeasmainLabel = uilabel(app.tenTab);
            app.imagesmustbesamesizeasmainLabel.Text = ' images must be same size as main';
            app.LoadSecretColorImageTab = uitab(app.TabGroup);
            app.LoadSecretColorImageTab.Title = 'Load Secret Color Image';
            app.hImgUIAxes = uiaxes(app.LoadSecretColorImageTab);
            title(app.hImgUIAxes, 'Title')
            xlabel(app.hImgUIAxes, 'X')
            ylabel(app.hImgUIAxes, 'Y')
            app.ColorImageLabel = uilabel(app.LoadSecretColorImageTab);
            app.ColorImageLabel.Text = 'Upload your Color Image:';
            app.revealColor = uibutton(app.LoadSecretColorImageTab, 'push');
            app.revealColor.ButtonPushedFcn = createCallbackFcn(app, @revealColorPushed, true);
            app.revealColor.Text = 'Reveal - Your Embedded Image';
            app.SecretButton = uibutton(app.LoadSecretColorImageTab, 'push');
            app.SecretButton.ButtonPushedFcn = createCallbackFcn(app, @SecretButtonPushed, true);
            app.SecretButton.Text = 'Upload - A Secret Image';
            app.infoColorLabel = uilabel(app.LoadSecretColorImageTab);
            app.infoColorLabel.Text = '*This encoding type can handle (B/W) and (C) images';
            app.infoColorLabel2 = uilabel(app.LoadSecretColorImageTab);
            app.infoColorLabel2.Text = 'For best results - upload an image nearly the same size as the main image';
            app.EmbeddedInfoLabel = uilabel(app.LoadSecretColorImageTab);
            app.EmbeddedInfoLabel.Text = '*This may take some time to load ';
            app.EnterSecretMessageTab = uitab(app.TabGroup);
            app.EnterSecretMessageTab.Title = 'Enter Secret Message';
            app.AddasecretmessageLabel = uilabel(app.EnterSecretMessageTab);
            app.AddasecretmessageLabel.Text = 'Add a secret message : ';
            app.secretMessageEditField = uieditfield(app.EnterSecretMessageTab, 'text');
            app.secretMessageEditField.ValueChangedFcn = createCallbackFcn(app, @secretMessageEditFieldValueChanged, true);
            app.embedMessageButton = uibutton(app.EnterSecretMessageTab, 'push');
            app.embedMessageButton.ButtonPushedFcn = createCallbackFcn(app, @embedMessageButtonPushed, true);
            app.embedMessageButton.Text = 'Reveal - Your Embedded Image';
            app.SecretLabel = uilabel(app.EnterSecretMessageTab);
            app.SecretLabel.Text = 'Enter a Secret Image:';
            app.InfoSecretLabel = uilabel(app.EnterSecretMessageTab);
            app.InfoSecretLabel.Text = '*Works best for small messages';
            app.EmbeddedInfoLabel_2 = uilabel(app.EnterSecretMessageTab);
            app.EmbeddedInfoLabel_2.Text = '*This may take some time to load ';
            app.EmbeddedImageTab = uitab(app.TabGroup);
            app.EmbeddedImageTab.Title = 'Embedded Image';
            app.EmbeddedLabel = uilabel(app.EmbeddedImageTab);
            app.EmbeddedLabel.Text = 'This is your Embedded Image:';
            app.embeddedUIAxes = uiaxes(app.EmbeddedImageTab);
            title(app.embeddedUIAxes, 'Title')
            xlabel(app.embeddedUIAxes, 'X')
            ylabel(app.embeddedUIAxes, 'Y')
            app.RecoverButton2 = uibutton(app.EmbeddedImageTab, 'push');
            app.RecoverButton2.Text = 'Decode an Image';
            app.RecoverInfoEmbedLabel = uilabel(app.EmbeddedImageTab);
            app.RecoverInfoEmbedLabel.Text = '*Now that you have an Embedded image you can decode it';
            app.saveImageButton = uibutton(app.EmbeddedImageTab, 'push');
            app.saveImageButton.ButtonPushedFcn = createCallbackFcn(app, @saveImageButtonPushed, true);
            app.saveImageButton.Text = 'Save your Image';
            app.SaveyourimageinpngforbestresultsLabel = uilabel(app.EmbeddedImageTab);
            app.SaveyourimageinpngforbestresultsLabel.Text = '*Save your image in .png for best results';
            app.RecoverButton2_2 = uibutton(app.EmbeddedImageTab, 'push');
            app.RecoverButton2_2.ButtonPushedFcn = createCallbackFcn(app, @RecoverButton2_2Pushed, true);
            app.RecoverButton2_2.Text = 'Decode an Image';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end