classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        UploadEmbeddedImageTab        matlab.ui.container.Tab
        UploadEButton                 matlab.ui.control.Button
        eImgUIAxes                    matlab.ui.control.UIAxes
        EmbedButton                   matlab.ui.control.Button
        InfoELabel                    matlab.ui.control.Label
        NextEButton                   matlab.ui.control.Button
        ELabel                        matlab.ui.control.Label
        ClickELabel                   matlab.ui.control.Label
        EmbedInfoLabel                matlab.ui.control.Label
        DecodeAppLabel                matlab.ui.control.Label
        CRMatlabManiacs2019Label      matlab.ui.control.Label
        ThebuttonwillendthisappLabel  matlab.ui.control.Label
        DecodingTypeTab               matlab.ui.container.Tab
        messageRButton                matlab.ui.control.Button
        EmbedLabel                    matlab.ui.control.Label
        demoOddUIAxes                 matlab.ui.control.UIAxes
        demoColorUIAxes               matlab.ui.control.UIAxes
        demoMessageUIAxes             matlab.ui.control.UIAxes
        OEembedLabel                  matlab.ui.control.Label
        BinaryLabel                   matlab.ui.control.Label
        BinaryLabel2                  matlab.ui.control.Label
        ColorButton                   matlab.ui.control.Button
        suggestLabel3                 matlab.ui.control.Label
        suggestLabel3_2               matlab.ui.control.Label
        suggestLabel3_3               matlab.ui.control.Label
        ThismaytakesometimetodecodeLabel  matlab.ui.control.Label
        threeButton                   matlab.ui.control.Button
        twoButton                     matlab.ui.control.Button
        oneButton                     matlab.ui.control.Button
        tenButton                     matlab.ui.control.Button
        oneTab                        matlab.ui.container.Tab
        EmbeddedLabel_3               matlab.ui.control.Label
        singleUIAxes                  matlab.ui.control.UIAxes
        singleButton                  matlab.ui.control.Button
        embedSingleButton             matlab.ui.control.Button
        EmbedInfoLabel_4              matlab.ui.control.Label
        twoTab                        matlab.ui.container.Tab
        EmbeddedLabel_4               matlab.ui.control.Label
        embedDoubleButton             matlab.ui.control.Button
        EmbedInfoLabel_5              matlab.ui.control.Label
        twoUIAxes1                    matlab.ui.control.UIAxes
        doubleButton1                 matlab.ui.control.Button
        twoUIAxes2                    matlab.ui.control.UIAxes
        doubleButton2                 matlab.ui.control.Button
        threeTab                      matlab.ui.container.Tab
        EmbeddedLabel_5               matlab.ui.control.Label
        embedThreeButton              matlab.ui.control.Button
        EmbedInfoLabel_6              matlab.ui.control.Label
        threeUIAxes1                  matlab.ui.control.UIAxes
        threeUIAxes2                  matlab.ui.control.UIAxes
        threeUIAxes3                  matlab.ui.control.UIAxes
        saveImageButton_2             matlab.ui.control.Button
        saveImageButton_3             matlab.ui.control.Button
        saveImageButton_4             matlab.ui.control.Button
        tenTab                        matlab.ui.container.Tab
        EmbeddedLabel_6               matlab.ui.control.Label
        embedThreeButton_2            matlab.ui.control.Button
        EmbedInfoLabel_7              matlab.ui.control.Label
        saveTen                       matlab.ui.control.Button
        ClicktosaveyourimagesinanewfolderofyourchoiceLabel  matlab.ui.control.Label
        RecoveredCImageTab            matlab.ui.container.Tab
        EmbeddedLabel                 matlab.ui.control.Label
        secretImgUIAxes               matlab.ui.control.UIAxes
        saveImageButton               matlab.ui.control.Button
        secretImgButton               matlab.ui.control.Button
        EmbedInfoLabel_2              matlab.ui.control.Label
        RecoveredMessageTab           matlab.ui.container.Tab
        EmbeddedLabel_2               matlab.ui.control.Label
        EmbedButton_3                 matlab.ui.control.Button
        EmbedInfoLabel_3              matlab.ui.control.Label
        SecretMessageTextAreaLabel    matlab.ui.control.Label
        SecretMessageTextArea         matlab.ui.control.TextArea
    end


    properties (Access = private)
        embeddedImg; % Description
        secretImgSingle;
        secretImgTwo1;
        secretImgTwo2;
        secretImgThree1;
        secretImgThree2;
        secretImgThree3;
        
        secretImgColor;
        secretMessage;
        
        sTen1Img;
        sTen2Img;
        sTen3Img;
        sTen4Img;
        sTen5Img;
        sTen6Img;
        sTen7Img;
        sTen8Img;
        sTen9Img;
        sTen10Img;
        
        tempBW = imread('blackAndwhite.png');
        tempColor = imread('colorTemp.png');
        tempMessage = imread('messageTemp.png');
        tempEmbedded = imread('EmbeddedTemp.png');
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %temp image embedded axes
            title(app.eImgUIAxes, []);
            xlabel(app.eImgUIAxes, []);
            ylabel(app.eImgUIAxes, []);
            app.eImgUIAxes.XAxis.TickLabels = {};
            app.eImgUIAxes.YAxis.TickLabels = {};
            imshow(app.tempEmbedded,'Parent', app.eImgUIAxes);
            
            %temp image BW axes
            title(app.demoOddUIAxes, []);
            xlabel(app.demoOddUIAxes, []);
            ylabel(app.demoOddUIAxes, []);
            app.demoOddUIAxes.XAxis.TickLabels = {};
            app.demoOddUIAxes.YAxis.TickLabels = {};
            imshow(app.tempBW,'Parent', app.demoOddUIAxes);
            
            %temp image C axes
            title(app.demoColorUIAxes, []);
            xlabel(app.demoColorUIAxes, []);
            ylabel(app.demoColorUIAxes, []);
            app.demoColorUIAxes.XAxis.TickLabels = {};
            app.demoColorUIAxes.YAxis.TickLabels = {};
            imshow(app.tempColor,'Parent', app.demoColorUIAxes);
            
            %temp image single axes
            title(app.singleUIAxes, []);
            xlabel(app.singleUIAxes, []);
            ylabel(app.singleUIAxes, []);
            app.singleUIAxes.XAxis.TickLabels = {};
            app.singleUIAxes.YAxis.TickLabels = {};
            imshow(app.tempBW,'Parent', app.singleUIAxes);
            
            %temp image double axes
            title(app.twoUIAxes1, []);
            xlabel(app.twoUIAxes1, []);
            ylabel(app.twoUIAxes1, []);
            app.twoUIAxes1.XAxis.TickLabels = {};
            app.twoUIAxes1.YAxis.TickLabels = {};
            imshow(app.tempBW,'Parent', app.twoUIAxes1);
            title(app.twoUIAxes2, []);
            xlabel(app.twoUIAxes2, []);
            ylabel(app.twoUIAxes2, []);
            app.twoUIAxes2.XAxis.TickLabels = {};
            app.twoUIAxes2.YAxis.TickLabels = {};
            imshow(app.tempBW,'Parent', app.twoUIAxes2);
            
            %temp image three axies
            title(app.threeUIAxes1, []);
            xlabel(app.threeUIAxes1, []);
            ylabel(app.threeUIAxes1, []);
            app.threeUIAxes1.XAxis.TickLabels = {};
            app.threeUIAxes1.YAxis.TickLabels = {};
            imshow(app.tempBW,'Parent', app.threeUIAxes1);
            title(app.threeUIAxes2, []);
            xlabel(app.threeUIAxes2, []);
            ylabel(app.threeUIAxes2, []);
            app.threeUIAxes2.XAxis.TickLabels = {};
            app.threeUIAxes2.YAxis.TickLabels = {};
            imshow(app.tempBW,'Parent', app.threeUIAxes2);
            title(app.threeUIAxes3, []);
            xlabel(app.threeUIAxes3, []);
            ylabel(app.threeUIAxes3, []);
            app.threeUIAxes3.XAxis.TickLabels = {};
            app.threeUIAxes3.YAxis.TickLabels = {};
            imshow(app.tempBW,'Parent', app.threeUIAxes3);


            %temp image message axes
            title(app.demoMessageUIAxes, []);
            xlabel(app.demoMessageUIAxes, []);
            ylabel(app.demoMessageUIAxes, []);
            app.demoMessageUIAxes.XAxis.TickLabels = {};
            app.demoMessageUIAxes.YAxis.TickLabels = {};
            imshow(app.tempMessage,'Parent', app.demoMessageUIAxes);
            
            %temp image sec color axes
            title(app.secretImgUIAxes, []);
            xlabel(app.secretImgUIAxes, []);
            ylabel(app.secretImgUIAxes, []);
            app.secretImgUIAxes.XAxis.TickLabels = {};
            app.secretImgUIAxes.YAxis.TickLabels = {};
            imshow(app.tempColor,'Parent', app.secretImgUIAxes);
            
           
        end

        % Button pushed function: UploadEButton
        function UploadEButtonPushed(app, event)
            
            app.embeddedImg=imread(uigetfile({'*.png';'*.jpg'}, 'Select file'));
            imshow(app.embeddedImg,'Parent', app.eImgUIAxes);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
        end

        % Button pushed function: NextEButton
        function NextEButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.DecodingTypeTab;
        end

        % Button pushed function: ColorButton
        function ColorButtonPushed(app, event)
            %add code to decode embedded image to get color image
            %display on other axis
            
            app.secretImgColor=recoverColorImage(app.embeddedImg);
            
            imshow(app.secretImgColor,'Parent', app.secretImgUIAxes);
            
            app.TabGroup.SelectedTab = app.RecoveredCImageTab;
        end

        % Button pushed function: messageRButton
        function messageRButtonPushed(app, event)
            %add code to decode message from embedded image 
            
            app.secretMessage = recoverMessage(app.embeddedImg);
            
            app.SecretMessageTextArea.Value = app.secretMessage;
            
            app.TabGroup.SelectedTab = app.RecoveredMessageTab;
        end

        % Button pushed function: saveImageButton
        function saveImageButtonPushed(app, event)
             imsave(app.secretImgUIAxes); 
        end

        % Button pushed function: EmbedButton
        function EmbedButtonPushed(app, event)
            delete(app);                 
            app1;
        end

        % Button pushed function: secretImgButton
        function secretImgButtonPushed(app, event)
            delete(app);                 
            app1;
        end

        % Button pushed function: EmbedButton_3
        function EmbedButton_3Pushed(app, event)
            delete(app);                 
            app1;
        end

        % Callback function
        function SingleButtonPushed(app, event)
            app.secretImgSingle=singleRec(app.embeddedImg);
            imshow(app.secretImgSingle,'Parent', app.singleUIAxes);           
            app.TabGroup.SelectedTab = app.oneTab;
        end

        % Callback function
        function TwoButtonPushed(app, event)
                        
            %decode for two bw image here
            
            %[app.secretImgTwo1,app.secretImgTwo2]=recoverColorImage(app.embeddedImg);
            
            title(app.twoUIAxes1, []);
            xlabel(app.twoUIAxes1, []);
            ylabel(app.twoUIAxes1, []);
            app.twoUIAxes1.XAxis.TickLabels = {};
            app.twoUIAxes1.YAxis.TickLabels = {};
            imshow(app.secretImgTwo1,'Parent', app.twoUIAxes1);
            
            title(app.twoUIAxes1, []);
            xlabel(app.twoUIAxes1, []);
            ylabel(app.twoUIAxes1, []);
            app.twoUIAxes1.XAxis.TickLabels = {};
            app.twoUIAxes1.YAxis.TickLabels = {};
            imshow(app.secretImgTwo2,'Parent', app.twoUIAxes1);


            app.TabGroup.SelectedTab = app.twoTab;
        end

        % Callback function
        function ThreeButtonPushed(app, event)
            
            %decode for three bw image here
            
            %[app.secretImgThree1,app.secretImgThree2,app.secretImgThree3]=recoverColorImage(app.embeddedImg);
            
            title(app.threeUIAxes1, []);
            xlabel(app.threeUIAxes1, []);
            ylabel(app.threeUIAxes1, []);
            app.threeUIAxes1.XAxis.TickLabels = {};
            app.threeUIAxes1.YAxis.TickLabels = {};
            imshow(app.secretImgTwo1,'Parent', app.threeUIAxes1);
            
            title(app.threeUIAxes2, []);
            xlabel(app.threeUIAxes2, []);
            ylabel(app.threeUIAxes2, []);
            app.threeUIAxes2.XAxis.TickLabels = {};
            app.threeUIAxes2.YAxis.TickLabels = {};
            imshow(app.secretImgTwo2,'Parent', app.threeUIAxes2);
            
            title(app.threeUIAxes3, []);
            xlabel(app.threeUIAxes3, []);
            ylabel(app.threeUIAxes3, []);
            app.threeUIAxes3.XAxis.TickLabels = {};
            app.threeUIAxes3.YAxis.TickLabels = {};
            imshow(app.secretImgTwo1,'Parent', app.threeUIAxes3);
                        
            app.TabGroup.SelectedTab = app.threeTab;
        end

        % Button pushed function: singleButton
        function singleButtonPushed(app, event)
            imsave(app.singleUIAxes); 
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: doubleButton1
        function doubleButton1Pushed(app, event)
            imsave(app.twoUIAxes1);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
        end

        % Button pushed function: doubleButton2
        function doubleButton2Pushed(app, event)
            imsave(app.twoUIAxes2); 
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Callback function
        function threeButton1Pushed(app, event)
            imsave(app.threeUIAxes1);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Callback function
        function threeButton2Pushed(app, event)
            imsave(app.threeUIAxes2); 
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Callback function
        function threeButton3Pushed(app, event)
            imsave(app.threeUIAxes3);
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: oneButton
        function oneButtonPushed(app, event)
            app.secretImgSingle=singleRec(app.embeddedImg);
            imshow(app.secretImgSingle,'Parent', app.singleUIAxes);           
            app.TabGroup.SelectedTab = app.oneTab;
        end

        % Button pushed function: twoButton
        function twoButtonPushed(app, event)
            [app.secretImgTwo1,app.secretImgTwo2]=doubleRec(app.embeddedImg);
            imshow(app.secretImgTwo1,'Parent', app.twoUIAxes1);
            imshow(app.secretImgTwo2,'Parent', app.twoUIAxes2);
            app.TabGroup.SelectedTab = app.twoTab;
        end

        % Button pushed function: threeButton
        function threeButtonPushed(app, event)
            [app.secretImgThree1,app.secretImgThree2,app.secretImgThree3]=tripleRec(app.embeddedImg);
            imshow(app.secretImgThree1,'Parent', app.threeUIAxes1);
            imshow(app.secretImgThree2,'Parent', app.threeUIAxes2);
            imshow(app.secretImgThree3,'Parent', app.threeUIAxes3);
            app.TabGroup.SelectedTab = app.threeTab;
        end

        % Button pushed function: tenButton
        function tenButtonPushed(app, event)
            [a,b,c,d,e,f,g,h,i,j] = tenRec(app.embeddedImg);
            app.sTen1Img=a;
        app.sTen2Img=b;
        app.sTen3Img=c;
        app.sTen4Img=d;
        app.sTen5Img=e;
        app.sTen6Img=f;
        app.sTen7Img=g;
        app.sTen8Img=h;
        app.sTen9Img=i;
        app.sTen10Img=j;
            app.TabGroup.SelectedTab = app.tenTab;
        end

        % Button pushed function: embedSingleButton
        function embedSingleButtonPushed(app, event)
            delete(app);                 
            app1;
        end

        % Button pushed function: embedDoubleButton
        function embedDoubleButtonPushed(app, event)
            delete(app);                 
            app1;
        end

        % Button pushed function: embedThreeButton
        function embedThreeButtonPushed(app, event)
            delete(app);                 
            app1;
        end

        % Button pushed function: saveTen
        function saveTenButtonPushed(app, event)
            hiddenDir = uigetdir();

            %%loop k for 1 to 10
            for k = (1:10)
                baseFileName = sprintf('Image%d.png', k);
                appFileName = sprintf('sTen%dImg',k);
                fullFileName = fullfile(hiddenDir, baseFileName);
                imwrite(app.(appFileName), fullFileName);
            end
        end

        % Button pushed function: saveImageButton_4
        function saveImageButton_4Pushed(app, event)
            imsave(app.threeUIAxes1); 
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: saveImageButton_2
        function saveImageButton_2Pushed(app, event)
            imsave(app.threeUIAxes2); 
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: saveImageButton_3
        function saveImageButton_3Pushed(app, event)
            imsave(app.threeUIAxes3); 
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: embedThreeButton_2
        function embedThreeButton_2Pushed(app, event)
            delete(app);                 
            app1;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UploadEmbeddedImageTab = uitab(app.TabGroup);
            app.UploadEmbeddedImageTab.Title = 'Upload Embedded Image';
            app.UploadEButton = uibutton(app.UploadEmbeddedImageTab, 'push');
            app.UploadEButton.ButtonPushedFcn = createCallbackFcn(app, @UploadEButtonPushed, true);
            app.UploadEButton.Text = 'Upload - Embedded Image';
            app.eImgUIAxes = uiaxes(app.UploadEmbeddedImageTab);
            title(app.eImgUIAxes, 'Title')
            xlabel(app.eImgUIAxes, 'X')
            ylabel(app.eImgUIAxes, 'Y')
            app.EmbedButton = uibutton(app.UploadEmbeddedImageTab, 'push');
            app.EmbedButton.ButtonPushedFcn = createCallbackFcn(app, @EmbedButtonPushed, true);
            app.EmbedButton.Text = 'Embed an Image';
            app.InfoELabel = uilabel(app.UploadEmbeddedImageTab);
            app.InfoELabel.Text = '*Your Embedded Image should have secret information.';
            app.NextEButton = uibutton(app.UploadEmbeddedImageTab, 'push');
            app.NextEButton.ButtonPushedFcn = createCallbackFcn(app, @NextEButtonPushed, true);
            app.NextEButton.Text = 'Next';
            app.ELabel = uilabel(app.UploadEmbeddedImageTab);
            app.ELabel.Text = 'Upload Your Embedded Image:';
            app.ClickELabel = uilabel(app.UploadEmbeddedImageTab);
            app.ClickELabel.Text = '*Click the button to load your image';
            app.EmbedInfoLabel = uilabel(app.UploadEmbeddedImageTab);
            app.EmbedInfoLabel.Text = '*Dont have an embedded image click here';
            app.DecodeAppLabel = uilabel(app.UploadEmbeddedImageTab);
            app.DecodeAppLabel.Text = 'Decode App';
            app.CRMatlabManiacs2019Label = uilabel(app.UploadEmbeddedImageTab);
            app.CRMatlabManiacs2019Label.Text = 'C.R Matlab Maniacs - 2019';
            app.ThebuttonwillendthisappLabel = uilabel(app.UploadEmbeddedImageTab);
            app.ThebuttonwillendthisappLabel.Text = '*The button will end this app';
            app.DecodingTypeTab = uitab(app.TabGroup);
            app.DecodingTypeTab.Title = 'Decoding Type';
            app.messageRButton = uibutton(app.DecodingTypeTab, 'push');
            app.messageRButton.ButtonPushedFcn = createCallbackFcn(app, @messageRButtonPushed, true);
            app.messageRButton.Text = 'Recover a Message';
            app.EmbedLabel = uilabel(app.DecodingTypeTab);
            app.EmbedLabel.Text = 'Select the Information you would like to Decode:';
            app.demoOddUIAxes = uiaxes(app.DecodingTypeTab);
            title(app.demoOddUIAxes, 'Title')
            xlabel(app.demoOddUIAxes, 'X')
            ylabel(app.demoOddUIAxes, 'Y')
            app.demoColorUIAxes = uiaxes(app.DecodingTypeTab);
            title(app.demoColorUIAxes, 'Title')
            xlabel(app.demoColorUIAxes, 'X')
            ylabel(app.demoColorUIAxes, 'Y')
            app.demoMessageUIAxes = uiaxes(app.DecodingTypeTab);
            title(app.demoMessageUIAxes, 'Title')
            xlabel(app.demoMessageUIAxes, 'X')
            ylabel(app.demoMessageUIAxes, 'Y')
            app.OEembedLabel = uilabel(app.DecodingTypeTab);
            app.OEembedLabel.Text = '*Recovery Odd/Even  ';
            app.BinaryLabel = uilabel(app.DecodingTypeTab);
            app.BinaryLabel.Text = '*Recovery Binary Method ';
            app.BinaryLabel2 = uilabel(app.DecodingTypeTab);
            app.BinaryLabel2.Text = '*Recovery Binary';
            app.ColorButton = uibutton(app.DecodingTypeTab, 'push');
            app.ColorButton.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPushed, true);
            app.ColorButton.Text = 'Recover Single Image (C)';
            app.suggestLabel3 = uilabel(app.DecodingTypeTab);
            app.suggestLabel3.Text = 'usually small messages';
            app.suggestLabel3_2 = uilabel(app.DecodingTypeTab);
            app.suggestLabel3_2.Text = '  usually color images - (C)';
            app.suggestLabel3_3 = uilabel(app.DecodingTypeTab);
            app.suggestLabel3_3.Text = '  usually black / white images';
            app.ThismaytakesometimetodecodeLabel = uilabel(app.DecodingTypeTab);
            app.ThismaytakesometimetodecodeLabel.Text = '*This may take some time to decode';
            app.threeButton = uibutton(app.DecodingTypeTab, 'push');
            app.threeButton.ButtonPushedFcn = createCallbackFcn(app, @threeButtonPushed, true);
            app.threeButton.Text = '3 Images (B/W)';
            app.twoButton = uibutton(app.DecodingTypeTab, 'push');
            app.twoButton.ButtonPushedFcn = createCallbackFcn(app, @twoButtonPushed, true);
            app.twoButton.Text = '2 Images (B/W)';
            app.oneButton = uibutton(app.DecodingTypeTab, 'push');
            app.oneButton.ButtonPushedFcn = createCallbackFcn(app, @oneButtonPushed, true);
            app.oneButton.Text = '1 Image (B/W)';
            app.tenButton = uibutton(app.DecodingTypeTab, 'push');
            app.tenButton.ButtonPushedFcn = createCallbackFcn(app, @tenButtonPushed, true);
            app.tenButton.Text = '10 Images (B/W)';
            app.oneTab = uitab(app.TabGroup);
            app.oneTab.Title = 'Recovered (B/W) Single Image';
            app.EmbeddedLabel_3 = uilabel(app.oneTab);
            app.EmbeddedLabel_3.Text = 'This is your Secret Image:';
            app.singleUIAxes = uiaxes(app.oneTab);
            title(app.singleUIAxes, 'Title')
            xlabel(app.singleUIAxes, 'X')
            ylabel(app.singleUIAxes, 'Y')
            app.singleButton = uibutton(app.oneTab, 'push');
            app.singleButton.ButtonPushedFcn = createCallbackFcn(app, @singleButtonPushed, true);
            app.singleButton.Text = 'Save your Image';
            app.embedSingleButton = uibutton(app.oneTab, 'push');
            app.embedSingleButton.ButtonPushedFcn = createCallbackFcn(app, @embedSingleButtonPushed, true);
            app.embedSingleButton.Text = 'Embed an Image';
            app.EmbedInfoLabel_4 = uilabel(app.oneTab);
            app.EmbedInfoLabel_4.Text = '*Click here to embed another image';
            app.twoTab = uitab(app.TabGroup);
            app.twoTab.Title = 'Recovered (B/W) Two Images';
            app.EmbeddedLabel_4 = uilabel(app.twoTab);
            app.EmbeddedLabel_4.Text = 'These are your Secret Images:';
            app.embedDoubleButton = uibutton(app.twoTab, 'push');
            app.embedDoubleButton.ButtonPushedFcn = createCallbackFcn(app, @embedDoubleButtonPushed, true);
            app.embedDoubleButton.Text = 'Embed an Image';
            app.EmbedInfoLabel_5 = uilabel(app.twoTab);
            app.EmbedInfoLabel_5.Text = '*Click here to embed another image';
            app.twoUIAxes1 = uiaxes(app.twoTab);
            title(app.twoUIAxes1, 'Title')
            xlabel(app.twoUIAxes1, 'X')
            ylabel(app.twoUIAxes1, 'Y')
            app.doubleButton1 = uibutton(app.twoTab, 'push');
            app.doubleButton1.ButtonPushedFcn = createCallbackFcn(app, @doubleButton1Pushed, true);
            app.doubleButton1.Text = 'Save your Image';
            app.twoUIAxes2 = uiaxes(app.twoTab);
            title(app.twoUIAxes2, 'Title')
            xlabel(app.twoUIAxes2, 'X')
            ylabel(app.twoUIAxes2, 'Y')
            app.doubleButton2 = uibutton(app.twoTab, 'push');
            app.doubleButton2.ButtonPushedFcn = createCallbackFcn(app, @doubleButton2Pushed, true);
            app.doubleButton2.Text = 'Save your Image';
            app.threeTab = uitab(app.TabGroup);
            app.threeTab.Title = 'Recovered (B/W) Three Images';
            app.EmbeddedLabel_5 = uilabel(app.threeTab);
            app.EmbeddedLabel_5.Text = 'These are your Secret Images:';
            app.embedThreeButton = uibutton(app.threeTab, 'push');
            app.embedThreeButton.ButtonPushedFcn = createCallbackFcn(app, @embedThreeButtonPushed, true);
            app.embedThreeButton.Text = 'Embed an Image';
            app.EmbedInfoLabel_6 = uilabel(app.threeTab);
            app.EmbedInfoLabel_6.Text = '*Click here to embed another image';
            app.threeUIAxes1 = uiaxes(app.threeTab);
            title(app.threeUIAxes1, 'Title')
            xlabel(app.threeUIAxes1, 'X')
            ylabel(app.threeUIAxes1, 'Y')
            app.threeUIAxes2 = uiaxes(app.threeTab);
            title(app.threeUIAxes2, 'Title')
            xlabel(app.threeUIAxes2, 'X')
            ylabel(app.threeUIAxes2, 'Y')
            app.threeUIAxes3 = uiaxes(app.threeTab);
            title(app.threeUIAxes3, 'Title')
            xlabel(app.threeUIAxes3, 'X')
            ylabel(app.threeUIAxes3, 'Y')
            app.saveImageButton_2 = uibutton(app.threeTab, 'push');
            app.saveImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @saveImageButton_2Pushed, true);
            app.saveImageButton_2.Text = 'Save your Image';
            app.saveImageButton_3 = uibutton(app.threeTab, 'push');
            app.saveImageButton_3.ButtonPushedFcn = createCallbackFcn(app, @saveImageButton_3Pushed, true);
            app.saveImageButton_3.Text = 'Save your Image';
            app.saveImageButton_4 = uibutton(app.threeTab, 'push');
            app.saveImageButton_4.ButtonPushedFcn = createCallbackFcn(app, @saveImageButton_4Pushed, true);
            app.saveImageButton_4.Text = 'Save your Image';
            app.tenTab = uitab(app.TabGroup);
            app.tenTab.Title = 'Recovered (B/W) Ten Images';
            app.EmbeddedLabel_6 = uilabel(app.tenTab);
            app.EmbeddedLabel_6.Text = 'Your Ten Secret Images are shown Separately :';
            app.embedThreeButton_2 = uibutton(app.tenTab, 'push');
            app.embedThreeButton_2.ButtonPushedFcn = createCallbackFcn(app, @embedThreeButton_2Pushed, true);
            app.embedThreeButton_2.Text = 'Embed an Image';
            app.EmbedInfoLabel_7 = uilabel(app.tenTab);
            app.EmbedInfoLabel_7.Text = '*Click here to embed another image';
            app.saveTen = uibutton(app.tenTab, 'push');
            app.saveTen.ButtonPushedFcn = createCallbackFcn(app, @saveTenButtonPushed, true);
            app.saveTen.Text = 'Save your Images';
            app.ClicktosaveyourimagesinanewfolderofyourchoiceLabel = uilabel(app.tenTab);
            app.ClicktosaveyourimagesinanewfolderofyourchoiceLabel.Text = '*Click to save your images in a new folder of your choice:';
            app.RecoveredCImageTab = uitab(app.TabGroup);
            app.RecoveredCImageTab.Title = 'Recovered (C) Image';
            app.EmbeddedLabel = uilabel(app.RecoveredCImageTab);
            app.EmbeddedLabel.Text = 'This is your Secret Image:';
            app.secretImgUIAxes = uiaxes(app.RecoveredCImageTab);
            title(app.secretImgUIAxes, 'Title')
            xlabel(app.secretImgUIAxes, 'X')
            ylabel(app.secretImgUIAxes, 'Y')
            app.saveImageButton = uibutton(app.RecoveredCImageTab, 'push');
            app.saveImageButton.ButtonPushedFcn = createCallbackFcn(app, @saveImageButtonPushed, true);
            app.saveImageButton.Text = 'Save your Image';
            app.secretImgButton = uibutton(app.RecoveredCImageTab, 'push');
            app.secretImgButton.ButtonPushedFcn = createCallbackFcn(app, @secretImgButtonPushed, true);
            app.secretImgButton.Text = 'Embed an Image';
            app.EmbedInfoLabel_2 = uilabel(app.RecoveredCImageTab);
            app.EmbedInfoLabel_2.Text = '*Click here to embed another image';
            app.RecoveredMessageTab = uitab(app.TabGroup);
            app.RecoveredMessageTab.Title = 'Recovered Message';
            app.EmbeddedLabel_2 = uilabel(app.RecoveredMessageTab);
            app.EmbeddedLabel_2.Text = 'This is your Secret Message:';
            app.EmbedButton_3 = uibutton(app.RecoveredMessageTab, 'push');
            app.EmbedButton_3.ButtonPushedFcn = createCallbackFcn(app, @EmbedButton_3Pushed, true);
            app.EmbedButton_3.Text = 'Embed an Image';
            app.EmbedInfoLabel_3 = uilabel(app.RecoveredMessageTab);
            app.EmbedInfoLabel_3.Text = '*Click here to embed another image';
            app.SecretMessageTextAreaLabel = uilabel(app.RecoveredMessageTab);
            app.SecretMessageTextAreaLabel.Text = 'Secret Message';
            app.SecretMessageTextArea = uitextarea(app.RecoveredMessageTab);
            app.SecretMessageTextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end